classdef DMDBoxPositioning < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        BoxheightbhEditFieldLabel  matlab.ui.control.Label
        BoxheightbhEditField       matlab.ui.control.NumericEditField
        OKLabel                    matlab.ui.control.Label
        OKLabel_2                  matlab.ui.control.Label
        BoxwidthbwEditFieldLabel   matlab.ui.control.Label
        BoxwidthbwEditField        matlab.ui.control.NumericEditField
        OKLabel_3                  matlab.ui.control.Label
        TopbufferEditFieldLabel    matlab.ui.control.Label
        TopbufferEditField         matlab.ui.control.NumericEditField
        OKLabel_4                  matlab.ui.control.Label
        LeftbufferEditFieldLabel   matlab.ui.control.Label
        LeftbufferEditField        matlab.ui.control.NumericEditField
        LoadonDMDButton            matlab.ui.control.Button
        DMDBoxPositioningLabel     matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        bh % box height
        bw % box width
        topBuffer
        leftBuffer
    end
    
    methods (Access = private)
        
        function results = VerticalCheck(app, myNum)
            c1 = (myNum == round(myNum));
            c2 = (myNum>=0 && myNum<1080);
            results =  c1 && c2;
        end
        function results = HorizontalCheck(app, myNum)
            c1 = (myNum == round(myNum));
            c2 = (myNum>=0 && myNum<1920);
            c3 = (mod(myNum, 8) == 0);
            results = c1 && c2 && c3;
        end
        function Update(app)
            cla(app.UIAxes);
            app.topBuffer = app.TopbufferEditField.Value;
            if VerticalCheck(app, app.topBuffer)
                app.OKLabel_3.Text = 'OK!';
                app.OKLabel_3.FontColor = [0.3922 0.8314 0.0745];
            else
                app.OKLabel_3.Text = 'Invalid!';
                app.OKLabel_3.FontColor = [1 0 0];
            end
            app.leftBuffer = app.LeftbufferEditField.Value;
            if HorizontalCheck(app, app.leftBuffer)
                app.OKLabel_4.Text = 'OK!';
                app.OKLabel_4.FontColor = [0.3922 0.8314 0.0745];
            else
                app.OKLabel_4.Text = 'Invalid!';
                app.OKLabel_4.FontColor = [1 0 0];
            end
            app.bh = app.BoxheightbhEditField.Value;
            app.bw = app.BoxwidthbwEditField.Value;
            rectangle(app.UIAxes,'Position', ...
                [app.leftBuffer, app.topBuffer, app.bh, app.bw], ...
                'FaceColor',[1,1,1]);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            SetPaths
            Update(app);
        end

        % Value changed function: BoxheightbhEditField, 
        % BoxwidthbwEditField, LeftbufferEditField, 
        % TopbufferEditField
        function BoxheightbhEditFieldValueChanged(app, event)
            Update(app);
        end

        % Button pushed function: LoadonDMDButton
        function LoadonDMDButtonPushed(app, event)
            ActiveBoxPositioning(app.bh, app.bw, app.topBuffer, app.leftBuffer)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BoxheightbhEditFieldLabel = uilabel(app.UIFigure);
            app.BoxheightbhEditFieldLabel.Text = 'Box height (bh)';
            app.BoxheightbhEditField = uieditfield(app.UIFigure, 'numeric');
            app.BoxheightbhEditField.ValueChangedFcn = createCallbackFcn(app, @BoxheightbhEditFieldValueChanged, true);
            app.BoxheightbhEditField.Value = 512;
            app.OKLabel = uilabel(app.UIFigure);
            app.OKLabel.Text = 'OK!';
            app.OKLabel_2 = uilabel(app.UIFigure);
            app.OKLabel_2.Text = 'OK!';
            app.BoxwidthbwEditFieldLabel = uilabel(app.UIFigure);
            app.BoxwidthbwEditFieldLabel.Text = 'Box width (bw)';
            app.BoxwidthbwEditField = uieditfield(app.UIFigure, 'numeric');
            app.BoxwidthbwEditField.ValueChangedFcn = createCallbackFcn(app, @BoxheightbhEditFieldValueChanged, true);
            app.BoxwidthbwEditField.Value = 512;
            app.OKLabel_3 = uilabel(app.UIFigure);
            app.OKLabel_3.Text = 'OK!';
            app.TopbufferEditFieldLabel = uilabel(app.UIFigure);
            app.TopbufferEditFieldLabel.Text = 'Top buffer';
            app.TopbufferEditField = uieditfield(app.UIFigure, 'numeric');
            app.TopbufferEditField.ValueChangedFcn = createCallbackFcn(app, @BoxheightbhEditFieldValueChanged, true);
            app.TopbufferEditField.Value = 284;
            app.OKLabel_4 = uilabel(app.UIFigure);
            app.OKLabel_4.Text = 'OK!';
            app.LeftbufferEditFieldLabel = uilabel(app.UIFigure);
            app.LeftbufferEditFieldLabel.Text = 'Left buffer';
            app.LeftbufferEditField = uieditfield(app.UIFigure, 'numeric');
            app.LeftbufferEditField.ValueChangedFcn = createCallbackFcn(app, @BoxheightbhEditFieldValueChanged, true);
            app.LeftbufferEditField.Value = 704;
            app.LoadonDMDButton = uibutton(app.UIFigure, 'push');
            app.LoadonDMDButton.ButtonPushedFcn = createCallbackFcn(app, @LoadonDMDButtonPushed, true);
            app.LoadonDMDButton.Text = 'Load on DMD';
            app.DMDBoxPositioningLabel = uilabel(app.UIFigure);
            app.DMDBoxPositioningLabel.Text = 'DMD Box Positioning';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'DMD')
            xlabel(app.UIAxes, 'Columns')
            ylabel(app.UIAxes, 'Rows')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 1919];
            app.UIAxes.YLim = [0 1079];
            app.UIAxes.YDir = 'reverse';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DMDBoxPositioning

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end